classdef CorALate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TypeLabel                       matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        Panel_DIC                       matlab.ui.container.Panel
        Button_FIDIC                    matlab.ui.control.Button
        Button_qDIC                     matlab.ui.control.Button
        Button_ALDIC                    matlab.ui.control.Button
        Button_TPT                      matlab.ui.control.Button
        Button_DVCButton                matlab.ui.control.Button
        Button_DIC3DButton              matlab.ui.control.Button
        Button_DICButton                matlab.ui.control.Button
        LoadExperimentDataTab           matlab.ui.container.Tab
        TabGroup_ALDIC                  matlab.ui.container.TabGroup
        LoadImagesTab                   matlab.ui.container.Tab
        ImageListLabel_2                matlab.ui.control.Label
        UITable_ImageList               matlab.ui.control.Table
        Button_LoadImages               matlab.ui.control.Button
        ReferenceLabel                  matlab.ui.control.Label
        DeformedLabel                   matlab.ui.control.Label
        UIAxes_DefImg                   matlab.ui.control.UIAxes
        UIAxes_RefImg                   matlab.ui.control.UIAxes
        LoadROITab                      matlab.ui.container.Tab
        ApplyROIPanel                   matlab.ui.container.Panel
        Button_ClearAll                 matlab.ui.control.Button
        Button_Applytoall               matlab.ui.control.Button
        Button_Applytoselected          matlab.ui.control.Button
        Button_ClearROI                 matlab.ui.control.Button
        AutoROIPanel                    matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        FilterbasedTab                  matlab.ui.container.Tab
        orderEditField                  matlab.ui.control.NumericEditField
        orderEditFieldLabel             matlab.ui.control.Label
        filterboundsLabel               matlab.ui.control.Label
        highEditField                   matlab.ui.control.NumericEditField
        highEditFieldLabel              matlab.ui.control.Label
        lowEditField                    matlab.ui.control.NumericEditField
        lowEditFieldLabel               matlab.ui.control.Label
        Button_SelectDICpattern_AutoROI  matlab.ui.control.Button
        graphsegmenterTab               matlab.ui.container.Tab
        GenerateROIButton               matlab.ui.control.Button
        selectbackgroundButton          matlab.ui.control.Button
        selectforegroundButton          matlab.ui.control.Button
        ModifyROIPanel                  matlab.ui.container.Panel
        Button_clearMask                matlab.ui.control.Button
        Button_makeMask                 matlab.ui.control.Button
        Button_elip_neg                 matlab.ui.control.Button
        Button_poly_neg                 matlab.ui.control.Button
        Button_elip_pos                 matlab.ui.control.Button
        Button_poly_pos                 matlab.ui.control.Button
        Button_cir_neg                  matlab.ui.control.Button
        Button_rect_neg                 matlab.ui.control.Button
        SubtractmaskLabel               matlab.ui.control.Label
        Button_cir_pos                  matlab.ui.control.Button
        AddmaskLabel                    matlab.ui.control.Label
        Button_rect_pos                 matlab.ui.control.Button
        Button_LoadROI_single           matlab.ui.control.Button
        UITable_ImageList_ROI           matlab.ui.control.Table
        Button_LoadROI_set              matlab.ui.control.Button
        ImageListLabel                  matlab.ui.control.Label
        UIAxes_ROI                      matlab.ui.control.UIAxes
        StartTab                        matlab.ui.container.Tab
        AnalysisTabGroup_ALDIC          matlab.ui.container.TabGroup
        AnalysislistTab_ALDIC           matlab.ui.container.Tab
        AnalysistypeALDICLabel          matlab.ui.control.Label
        StartnewanalysisButton_ALDIC    matlab.ui.control.Button
        AnalysisnameEditField_ALDIC     matlab.ui.control.EditField
        AnalysisnameEditFieldLabel      matlab.ui.control.Label
        SetinitialdisplacementguessTab_ALDIC  matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        IDG_DeformedImage_ALDIC         matlab.ui.control.UIAxes
        IDG_ReferenceImage_ALDIC        matlab.ui.control.UIAxes
        maxdisplacementEditField_ALDIC  matlab.ui.control.NumericEditField
        maxdisplacementEditFieldLabel   matlab.ui.control.Label
        SearchsettingsPanel_ALDIC       matlab.ui.container.Panel
        searchstepsizeEditField_ALDIC   matlab.ui.control.NumericEditField
        searchstepsizeEditFieldLabel    matlab.ui.control.Label
        searchregionsizeEditField_ALDIC  matlab.ui.control.NumericEditField
        searchregionsizeEditFieldLabel  matlab.ui.control.Label
        StartsearchButton_ALDIC         matlab.ui.control.Button
        RadioGroup_InitialguessMethod_ALDIC  matlab.ui.container.ButtonGroup
        Radio_Manualseed_ALDIC          matlab.ui.control.RadioButton
        Radio_Wholefieldsearch_ALDIC    matlab.ui.control.RadioButton
        Radio_Multigridsearch_ALDIC     matlab.ui.control.RadioButton
        SetDICparametersTab_ALDIC       matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        A_Vdisp_ALDIC                   matlab.ui.control.UIAxes
        A_Udisp_ALDIC                   matlab.ui.control.UIAxes
        StartAnalysis_ALDIC             matlab.ui.control.Button
        InitialguessoptionsPanel_ALDIC  matlab.ui.container.Panel
        UsemostrecentframeasguessCheckBox_ALDIC  matlab.ui.control.CheckBox
        UsePODCheckBox_ALDIC            matlab.ui.control.CheckBox
        ALDICOptionsPanel_ALDIC         matlab.ui.container.Panel
        muEditField_ALDIC               matlab.ui.control.NumericEditField
        muEditFieldLabel_2              matlab.ui.control.Label
        toleranceEditField_ALDIC        matlab.ui.control.NumericEditField
        toleranceEditFieldLabel_2       matlab.ui.control.Label
        stepsizeEditField_ALDIC         matlab.ui.control.NumericEditField
        stepsizeEditFieldLabel_2        matlab.ui.control.Label
        windowsizeEditField_ALDIC       matlab.ui.control.NumericEditField
        windowsizeEditFieldLabel_2      matlab.ui.control.Label
        Subproblem1MethodButtonGroup_ALDIC  matlab.ui.container.ButtonGroup
        GaussNewtonButton_ALDIC         matlab.ui.control.RadioButton
        LevenbergMarquardtButton_ALDIC  matlab.ui.control.RadioButton
        Subproblem2MethodButtonGroup_ALDIC  matlab.ui.container.ButtonGroup
        FiniteElementMethodButton_ALDIC  matlab.ui.control.RadioButton
        FiniteDifferenceButton_ALDIC    matlab.ui.control.RadioButton
        numberofparallelpoolsSpinner_ALDIC  matlab.ui.control.Spinner
        numberofparallelpoolsSpinnerLabel  matlab.ui.control.Label
        DisplacementsTab                matlab.ui.container.Tab
        SelectAnalysisListBox_Displacements  matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel      matlab.ui.control.Label
        StrainsTab                      matlab.ui.container.Tab
        SelectAnalysisListBox_Strains   matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel_4    matlab.ui.control.Label
        ExportDataTab                   matlab.ui.container.Tab
        SelectAnalysisListBox_Export    matlab.ui.control.ListBox
        SelectAnalysisListBoxLabel_5    matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        
        % ImageLoad 
        ImageFilePath = []; ImageFileNames = []; ImgSelected = 1;
        AllImages={};
        % ROI creation
        ROIFilePath = []; ROIFileNames = []; ROISelected = 1;
        ROIMask = []; AllROIMasks = {};
           % Auto ROI filter based
           filterRange = []; filterOrder = [];
           % Auto ROI graph based
           Background = []; Foreground = [];
           
        % this stores the type of DIC analysis that is being preformed on the data   
        Analysistype = [];
        SavedAnalysis = {};
        CurrentAnalysis = 0;
        CurrentAnalysisNum = 0;
        CurrentAnalysisName = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_ALDIC
        function Button_ALDICPushed(app, event)
            % setup the options for ALDIC
            app.TabGroup_ALDIC.Position = [1,1,1200,617];
            app.AnalysisTabGroup_ALDIC.Position = [1,1,1200,617];
            app.TabGroup.SelectedTab = app.LoadExperimentDataTab;
            app.Analysistype = 'ALDIC';
        end

        % Button pushed function: Button_LoadImages
        function Button_LoadImagesPushed(app, event)
            % open a browser window to select images and return image
            % filepaths and names
            [app.ImageFilePath, app.ImageFileNames] = Func_ImageLoad('multi','on');
            
            % clear the workspace
            workspace = 'loadImages';
            script_resetworkspace
            
            IMs =app.ImageFileNames;
            
            % set the data in the UItable_ImageList to the imagefiles
            % selected
            app.UITable_ImageList.Data = [];
            app.UITable_ImageList.Data = IMs';
            app.UITable_ImageList_ROI.Data = [];
            % set the imagelist in the ROI field as well
            app.UITable_ImageList_ROI.Data = IMs';
            % set the reference image to false for all images, then true
            % for the first 
            app.UITable_ImageList.Data(:,2) = num2cell(false(1,length(app.ImageFileNames)));
            app.UITable_ImageList.Data(1,2) = num2cell(true);
            % set the ROI list to have no associated ROI's
            app.UITable_ImageList_ROI.Data(:,2) = num2cell(false(1,length(app.ImageFileNames)));

            % set the image in the reference viewer to be the first image
            func_PlaceFigure(app.UIAxes_RefImg,imread(app.ImageFilePath{1}));
            % set the image in the deformed viewer to be the second image
            func_PlaceFigure(app.UIAxes_DefImg,imread(app.ImageFilePath{2}));
            
            % set the ROI axes to the first image
            IM = imread(app.ImageFilePath{app.ROISelected(1)});
            app.ROIMask = zeros(size(IM));
            ROIfig = imageoverlay2(IM,app.ROIMask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);
            
            % initialize an array of ROI masks
            app.AllROIMasks = {};
            for i=1:length(app.ImageFilePath)
                app.AllROIMasks{i}=[];
                %load all Images to memory
                app.AllImages{i} = imread(app.ImageFilePath{i});
            end
            
        end

        % Cell selection callback: UITable_ImageList
        function UITable_ImageListCellSelection(app, event)
            % get the index of the image 
            indices = event.Indices;
            app.ImgSelected = indices;
            % temp variable for the image list
            IMLst = app.ImageFilePath;
            refLst = app.UITable_ImageList.Data(:,2)';
            % set the image in the reference viewer to be the first image
            % that is true wrt the currently selected image
            firstRef_Img = [fliplr(IMLst(1:indices(1,1))) , IMLst(indices(1,1)+1:end)];
            firstRef_bool = [fliplr(refLst(1:indices(1,1))) , refLst(indices(1,1)+1:end)];
            refImg = firstRef_Img{find(cell2mat(firstRef_bool)==1,1)};
            defImg = IMLst{indices(1,1)};
            
            func_PlaceFigure(app.UIAxes_RefImg,imread(refImg));
            func_PlaceFigure(app.UIAxes_DefImg,imread(defImg));

        end

        % Button pushed function: Button_LoadROI_set
        function Button_LoadROI_setPushed(app, event)
            [app.ROIFilePath, app.ROIFileNames] = Func_ImageLoad('multi','on');
            % check to see that the number of selected images is the same
            % as the number of images 
            if isequal(length(app.ImageFileNames),length(app.ROIFileNames))
                
                Im = rgb2gray(imread(app.ImageFilePath{1}));
                
                for i=1:numel(app.ROIFilePath)
                    mask = rgb2gray(imread(app.ROIFilePath{i}));
                    % ensure mask is binary
                    mask = imbinarize(mask);
                    
                    % make sure mask is the same size as the image
                    if isequal(size(Im),size(mask))
                        app.AllROIMasks{i} = logical(mask);
                    else
                        return
                    end
                end
                
                % now load that ROI as an alpha map over top of the
                % 1st image
                background = imread(app.ImageFilePath{1});
                foreground = app.AllROIMasks{1};
                ROIfig = imageoverlay2(background,foreground,0.5);
                func_PlaceFigure(app.UIAxes_ROI,ROIfig);
                
                % set all images to have ROI
                app.UITable_ImageList_ROI.Data(:,2) = num2cell(true(1,length(app.ImageFileNames)));
            end
            
        end

        % Button pushed function: Button_LoadROI_single
        function Button_LoadROI_singlePushed(app, event)
            % get the currently selected image on the list. if no image is
            % selected then default to 1
            [ROIfp, ROIfn] = Func_ImageLoad('multi','off');
            
            app.ROIFilePath{app.ROISelected(1)} = ROIfp;
            app.ROIFileNames{app.ROISelected(1)} = ROIfn;
            
            % load the image and mask files
            Im = rgb2gray(imread(app.ImageFilePath{app.ROISelected(1)}));
            mask = rgb2gray(imread(app.ROIFilePath{app.ROISelected(1)}));
            
            % ensure mask is binary
            mask = imbinarize(mask);
            
            % make sure mask is the same size as the image
            if isequal(size(Im),size(mask))
                app.AllROIMasks{app.ROISelected(1)} = logical(mask);
            else
                return
            end
            
            app.UITable_ImageList_ROI.Data(app.ROISelected(1),2) = num2cell(true);
            
            % now load that ROI as an alpha map over top of the
            % corresponding image
            background = imread(app.ImageFilePath{app.ROISelected(1)});
            foreground = imread(app.ROIFilePath{app.ROISelected(1)});
            ROIfig = imageoverlay2(background,foreground,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);
        end

        % Cell selection callback: UITable_ImageList_ROI
        function UITable_ImageList_ROICellSelection(app, event)
            indices = event.Indices;
            app.ROISelected = indices;
            
            
            if isempty(app.AllROIMasks{app.ROISelected(1)})
                background = imread(app.ImageFilePath{app.ROISelected(1)});
                if isempty(app.ROIMask)
                    foreground = zeros(size(background));
                else
                    foreground = app.ROIMask;
                end
                
                ROIfig = imageoverlay2(background,foreground,0.5);
                func_PlaceFigure(app.UIAxes_ROI,ROIfig);
            else
                background = imread(app.ImageFilePath{app.ROISelected(1)});
                foreground = app.AllROIMasks{app.ROISelected(1)};
                ROIfig = imageoverlay2(background,foreground,0.5);
                func_PlaceFigure(app.UIAxes_ROI,ROIfig);
                app.ROIMask = foreground;
            end

        end

        % Button pushed function: Button_SelectDICpattern_AutoROI
        function Button_SelectDICpattern_AutoROIPushed(app, event)

            Im = imread(app.ImageFilePath{app.ROISelected(1)}); % the the Image associated with the currently selected ROI image list
            script_fftCompare % <- mask   
            ROIfig = imageoverlay2(Im,mask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);
            
        end

        % Callback function
        function Button_Generate_AutoROIPushed(app, event)
            Im = imread(app.ImageFilePath{app.ROISelected(1)}); % the the Image associated with the currently selected ROI image list
            script_fftCompare
            ROIfig = imageoverlay2(Im,mask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);
        end

        % Button pushed function: selectforegroundButton
        function selectforegroundButtonPushed(app, event)
            drawrectangle(app.UIAxes_ROI,'Label','Speckle_Pattern','Color','g');
        end

        % Button pushed function: selectbackgroundButton
        function selectbackgroundButtonPushed(app, event)
            drawrectangle(app.UIAxes_ROI,'Label','Background','Color','r');
        end

        % Button pushed function: GenerateROIButton
        function GenerateROIButtonPushed(app, event)
            
            Im = imread(app.ImageFilePath{app.ROISelected(1)});
            app.Background = zeros(size(Im));
            app.Foreground = zeros(size(Im));
            
            for i=1:numel(app.UIAxes_ROI.Children)
                ax = app.UIAxes_ROI.Children(i);
                try
                    if isequal(ax.Label,'Background')
                        mask = createMask(ax,Im);
                        app.Background = app.Background + mask;
    
                    elseif isequal(ax.Label,'Speckle_Pattern')
                        mask = createMask(ax,Im);
                        app.Foreground = app.Foreground + mask;
    
                    end
                catch
                end
            end
            
        [L,~] = superpixels(Im,500);
        BW = lazysnapping(Im,L,app.Foreground,app.Background);    
        ROIfig = imageoverlay2(Im,BW,0.5);
        func_PlaceFigure(app.UIAxes_ROI,ROIfig);    
            
        end

        % Button pushed function: Button_rect_pos
        function Button_rect_posPushed(app, event)
            drawrectangle(app.UIAxes_ROI,'Label','add','Color','w');
        end

        % Button pushed function: Button_cir_pos
        function Button_cir_posPushed(app, event)
            drawcircle(app.UIAxes_ROI,'Label','add','Color','w');
        end

        % Button pushed function: Button_elip_pos
        function Button_elip_posPushed(app, event)
            drawellipse(app.UIAxes_ROI,'Label','add','Color','w');
        end

        % Button pushed function: Button_poly_pos
        function Button_poly_posPushed(app, event)
            drawpolygon(app.UIAxes_ROI,'Label','add','Color','w');
        end

        % Button pushed function: Button_rect_neg
        function Button_rect_negPushed(app, event)
            drawrectangle(app.UIAxes_ROI,'Label','subtract','Color','r');
        end

        % Button pushed function: Button_cir_neg
        function Button_cir_negPushed(app, event)
            drawcircle(app.UIAxes_ROI,'Label','subtract','Color','r');
        end

        % Button pushed function: Button_elip_neg
        function Button_elip_negPushed(app, event)
            drawellipse(app.UIAxes_ROI,'Label','subtract','Color','r');
        end

        % Button pushed function: Button_poly_neg
        function Button_poly_negPushed(app, event)
            drawpolygon(app.UIAxes_ROI,'Label','subtract','Color','r');
        end

        % Button pushed function: Button_ClearROI
        function Button_ClearROIPushed(app, event)

            % set all images to have ROI
            for i=1:length(app.ROISelected)
                app.AllROIMasks{app.ROISelected(i)} = [];
                app.UITable_ImageList_ROI.Data(app.ROISelected(i),2) = num2cell(false(1,1));
            end
            
            IM = imread(app.ImageFilePath{app.ROISelected(1)});
            app.ROIMask = zeros(size(IM));
            ROIfig = imageoverlay2(IM,app.ROIMask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);
        end

        % Button pushed function: Button_makeMask
        function Button_makeMaskPushed(app, event)
            % use the current image to get the size and to use it as a
            % background
            Im = imread(app.ImageFilePath{app.ROISelected(1)});
            
            % initialize the masks
            add2image = zeros(size(Im));
            subfromimage = zeros(size(Im));
            
            % go through the current axis and see if there are any shapes
            % drawn on it, if so use those shapes to make a mask
            for i=1:numel(app.UIAxes_ROI.Children)
                % the children are the rectangles, circles etc select one
                % of them
                ax = app.UIAxes_ROI.Children(i);
                % need to use a try and catch because some elements of the
                % axis may not have labels
                try
                    if isequal(ax.Label,'add') % if one of the objects has this label
                        mask = createMask(ax,Im);
                        add2image = add2image + mask;
                        add2image(add2image>1)=1; % if a mask overlaps then we dont care
                    elseif isequal(ax.Label,'subtract')
                        mask = createMask(ax,Im);
                        subfromimage = subfromimage + mask;
                        subfromimage(subfromimage>1)=1; % if a mask overlaps then we dont care
                    end
                catch
                    % do nothing
                end
            end
            
            % from all of the add and subtract rectangles, make a mask
            app.ROIMask = app.ROIMask + add2image;
            app.ROIMask(app.ROIMask>1) = 1;
            app.ROIMask = app.ROIMask - subfromimage;
            app.ROIMask(app.ROIMask<0) = 0;
            
            % overlay that mask and display it
            ROIfig = imageoverlay2(Im,app.ROIMask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig); 
            
        end

        % Button pushed function: Button_Applytoselected
        function Button_ApplytoselectedPushed(app, event)
            selected = app.ROISelected(:,1);
            for i=1:numel(selected)
                app.AllROIMasks{selected(i)} = logical(app.ROIMask);
                app.UITable_ImageList_ROI.Data(selected(i),2) = num2cell(true(1,1));
            end
        end

        % Button pushed function: Button_Applytoall
        function Button_ApplytoallPushed(app, event)
            % set all images to have the current ROI 
            for i=1:numel(app.ImageFilePath)
                app.AllROIMasks{i} = logical(app.ROIMask);
                app.UITable_ImageList_ROI.Data(i,2) = num2cell(true(1,1));
            end
        end

        % Button pushed function: Button_ClearAll
        function Button_ClearAllPushed(app, event)
            % cleae all ROIs
            for i=1:numel(app.ImageFilePath)
                app.AllROIMasks{app.ROISelected(i)} = [];
                app.UITable_ImageList_ROI.Data(app.ROISelected(i),2) = num2cell(false(1,1));
            end
            
            IM = imread(app.ImageFilePath{app.ROISelected(1)});
            app.ROIMask = zeros(size(IM));
            ROIfig = imageoverlay2(IM,app.ROIMask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);

        end

        % Button pushed function: Button_clearMask
        function Button_clearMaskPushed(app, event)
            IM = imread(app.ImageFilePath{app.ROISelected(1)});
            app.ROIMask = zeros(size(IM));
            ROIfig = imageoverlay2(IM,app.ROIMask,0.5);
            func_PlaceFigure(app.UIAxes_ROI,ROIfig);
        end

        % Button pushed function: Button_DICButton
        function Button_DICButtonPushed(app, event)
            app.Panel_DIC.Visible = true;
            
        end

        % Selection changed function: 
        % RadioGroup_InitialguessMethod_ALDIC
        function RadioGroup_InitialguessMethod_ALDICSelectionChanged(app, event)
            selectedButton = app.RadioGroup_InitialguessMethod_ALDIC.SelectedObject;
            
            if isequal(selectedButton,'Manual seed')
                
            end
            
        end

        % Value changed function: AnalysisnameEditField_ALDIC
        function AnalysisnameEditField_ALDICValueChanged(app, event)

            app.StartnewanalysisButton_ALDIC.Enable = true;
        end

        % Button pushed function: StartnewanalysisButton_ALDIC
        function StartnewanalysisButton_ALDICPushed(app, event)
            app.AnalysisnameEditField_ALDIC.Value = '';
            app.StartnewanalysisButton_ALDIC.Enable = false;
            
            % create new DIC object
            obj = ALDIC();
            obj.Name = app.CurrentAnalysisName;
            app.CurrentAnalysis = obj;
            app.CurrentAnalysisNum = size(app.SavedAnalysis,1)+1;
            
            pos = size(app.SavedAnalysis,1)+1;
            
            % store object in an array
            app.SavedAnalysis{pos,1} = obj;
            app.SavedAnalysis{pos,2} = obj.Name;
            app.SavedAnalysis{pos,3} = app.CurrentAnalysisNum;
            app.SavedAnalysis{pos,4} = 'ALDIC';
            
            % update the list of saved analysis
            % NTBD
            
            % make the next tab the current tab
            app.AnalysisTabGroup_ALDIC.SelectedTab = app.SetinitialdisplacementguessTab_ALDIC;
            
            % update the list of analysis objects 
            script_UpdateListOfObjects
            
        end

        % Button pushed function: StartsearchButton_ALDIC
        function StartsearchButton_ALDICPushed(app, event)
            
            InitialSearch(app);
            
        end

        % Button pushed function: StartAnalysis_ALDIC
        function StartAnalysis_ALDICPushed(app, event)
            
            StartALDIC(app);
            
            
        end

        % Value changed function: searchregionsizeEditField_ALDIC
        function searchregionsizeEditField_ALDICValueChanged(app, event)
            value = app.searchregionsizeEditField_ALDIC.Value;
            app.windowsizeEditField_ALDIC.Value = value;
        end

        % Value changed function: searchstepsizeEditField_ALDIC
        function searchstepsizeEditField_ALDICValueChanged(app, event)
            value = app.searchstepsizeEditField_ALDIC.Value;
            app.stepsizeEditField_ALDIC.Value = value;
        end

        % Value changing function: AnalysisnameEditField_ALDIC
        function AnalysisnameEditField_ALDICValueChanging(app, event)
            changingValue = event.Value;
            app.StartnewanalysisButton_ALDIC.Enable = true;
            app.CurrentAnalysisName = changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.Button_DICButton = uibutton(app.HomeTab, 'push');
            app.Button_DICButton.ButtonPushedFcn = createCallbackFcn(app, @Button_DICButtonPushed, true);
            app.Button_DICButton.Icon = 'DIC.png';
            app.Button_DICButton.Text = 'DIC';
            app.Button_DIC3DButton = uibutton(app.HomeTab, 'push');
            app.Button_DIC3DButton.Icon = 'DIC3D.png';
            app.Button_DIC3DButton.Enable = 'off';
            app.Button_DIC3DButton.Text = 'DIC 3D';
            app.Button_DVCButton = uibutton(app.HomeTab, 'push');
            app.Button_DVCButton.Icon = 'DVC.png';
            app.Button_DVCButton.Enable = 'off';
            app.Button_DVCButton.Text = {'DVC'; ''};
            app.Button_TPT = uibutton(app.HomeTab, 'push');
            app.Button_TPT.Icon = 'T_PT.png';
            app.Button_TPT.Enable = 'off';
            app.Button_TPT.Text = 'TPT';
            app.Panel_DIC = uipanel(app.HomeTab);
            app.Panel_DIC.Visible = 'off';
            app.Button_ALDIC = uibutton(app.Panel_DIC, 'push');
            app.Button_ALDIC.ButtonPushedFcn = createCallbackFcn(app, @Button_ALDICPushed, true);
            app.Button_ALDIC.Text = 'ALDIC';
            app.Button_qDIC = uibutton(app.Panel_DIC, 'push');
            app.Button_qDIC.Enable = 'off';
            app.Button_qDIC.Text = 'qDIC';
            app.Button_FIDIC = uibutton(app.Panel_DIC, 'push');
            app.Button_FIDIC.Enable = 'off';
            app.Button_FIDIC.Text = 'FIDIC';
            app.LoadExperimentDataTab = uitab(app.TabGroup);
            app.LoadExperimentDataTab.Title = 'Load Experiment Data';
            app.TabGroup_ALDIC = uitabgroup(app.LoadExperimentDataTab);
            app.LoadImagesTab = uitab(app.TabGroup_ALDIC);
            app.LoadImagesTab.Title = 'Load Images';
            app.UIAxes_RefImg = uiaxes(app.LoadImagesTab);
            app.UIAxes_RefImg.XTick = [];
            app.UIAxes_RefImg.YTick = [];
            app.UIAxes_DefImg = uiaxes(app.LoadImagesTab);
            app.UIAxes_DefImg.XTick = [];
            app.UIAxes_DefImg.YTick = [];
            app.DeformedLabel = uilabel(app.LoadImagesTab);
            app.DeformedLabel.Text = 'Deformed';
            app.ReferenceLabel = uilabel(app.LoadImagesTab);
            app.ReferenceLabel.Text = 'Reference';
            app.Button_LoadImages = uibutton(app.LoadImagesTab, 'push');
            app.Button_LoadImages.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadImagesPushed, true);
            app.Button_LoadImages.Text = 'Load Images';
            app.UITable_ImageList = uitable(app.LoadImagesTab);
            app.UITable_ImageList.ColumnName = {'Images'; 'ref'};
            app.UITable_ImageList.RowName = {};
            app.UITable_ImageList.ColumnEditable = [false true];
            app.UITable_ImageList.CellSelectionCallback = createCallbackFcn(app, @UITable_ImageListCellSelection, true);
            app.ImageListLabel_2 = uilabel(app.LoadImagesTab);
            app.ImageListLabel_2.Text = 'ImageList';
            app.LoadROITab = uitab(app.TabGroup_ALDIC);
            app.LoadROITab.Title = 'Load ROI';
            app.UIAxes_ROI = uiaxes(app.LoadROITab);
            app.UIAxes_ROI.XTick = [];
            app.UIAxes_ROI.YTick = [];
            app.ImageListLabel = uilabel(app.LoadROITab);
            app.ImageListLabel.Text = 'ImageList';
            app.Button_LoadROI_set = uibutton(app.LoadROITab, 'push');
            app.Button_LoadROI_set.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadROI_setPushed, true);
            app.Button_LoadROI_set.Text = 'Load ROI set';
            app.UITable_ImageList_ROI = uitable(app.LoadROITab);
            app.UITable_ImageList_ROI.ColumnName = {'Images'; 'Has ROI'};
            app.UITable_ImageList_ROI.RowName = {};
            app.UITable_ImageList_ROI.ColumnEditable = [false true];
            app.UITable_ImageList_ROI.CellSelectionCallback = createCallbackFcn(app, @UITable_ImageList_ROICellSelection, true);
            app.Button_LoadROI_single = uibutton(app.LoadROITab, 'push');
            app.Button_LoadROI_single.ButtonPushedFcn = createCallbackFcn(app, @Button_LoadROI_singlePushed, true);
            app.Button_LoadROI_single.Text = 'Load ROI for selection';
            app.ModifyROIPanel = uipanel(app.LoadROITab);
            app.ModifyROIPanel.Title = 'Modify ROI';
            app.Button_rect_pos = uibutton(app.ModifyROIPanel, 'push');
            app.Button_rect_pos.ButtonPushedFcn = createCallbackFcn(app, @Button_rect_posPushed, true);
            app.Button_rect_pos.Text = 'Rectangle (+)';
            app.AddmaskLabel = uilabel(app.ModifyROIPanel);
            app.AddmaskLabel.Text = 'Add + mask';
            app.Button_cir_pos = uibutton(app.ModifyROIPanel, 'push');
            app.Button_cir_pos.ButtonPushedFcn = createCallbackFcn(app, @Button_cir_posPushed, true);
            app.Button_cir_pos.Text = 'Circle (+)';
            app.SubtractmaskLabel = uilabel(app.ModifyROIPanel);
            app.SubtractmaskLabel.Text = 'Subtract (- mask)';
            app.Button_rect_neg = uibutton(app.ModifyROIPanel, 'push');
            app.Button_rect_neg.ButtonPushedFcn = createCallbackFcn(app, @Button_rect_negPushed, true);
            app.Button_rect_neg.Text = 'Rectangle (-)';
            app.Button_cir_neg = uibutton(app.ModifyROIPanel, 'push');
            app.Button_cir_neg.ButtonPushedFcn = createCallbackFcn(app, @Button_cir_negPushed, true);
            app.Button_cir_neg.Text = 'Circle (-)';
            app.Button_poly_pos = uibutton(app.ModifyROIPanel, 'push');
            app.Button_poly_pos.ButtonPushedFcn = createCallbackFcn(app, @Button_poly_posPushed, true);
            app.Button_poly_pos.Text = 'Poly Line (+)';
            app.Button_elip_pos = uibutton(app.ModifyROIPanel, 'push');
            app.Button_elip_pos.ButtonPushedFcn = createCallbackFcn(app, @Button_elip_posPushed, true);
            app.Button_elip_pos.Text = 'Ellipse (+)';
            app.Button_poly_neg = uibutton(app.ModifyROIPanel, 'push');
            app.Button_poly_neg.ButtonPushedFcn = createCallbackFcn(app, @Button_poly_negPushed, true);
            app.Button_poly_neg.Text = 'Poly Line (-)';
            app.Button_elip_neg = uibutton(app.ModifyROIPanel, 'push');
            app.Button_elip_neg.ButtonPushedFcn = createCallbackFcn(app, @Button_elip_negPushed, true);
            app.Button_elip_neg.Text = 'Ellipse (-)';
            app.Button_makeMask = uibutton(app.ModifyROIPanel, 'push');
            app.Button_makeMask.ButtonPushedFcn = createCallbackFcn(app, @Button_makeMaskPushed, true);
            app.Button_makeMask.Text = 'Make Mask';
            app.Button_clearMask = uibutton(app.ModifyROIPanel, 'push');
            app.Button_clearMask.ButtonPushedFcn = createCallbackFcn(app, @Button_clearMaskPushed, true);
            app.Button_clearMask.Text = 'Clear mask';
            app.AutoROIPanel = uipanel(app.LoadROITab);
            app.AutoROIPanel.Title = 'Auto ROI';
            app.TabGroup3 = uitabgroup(app.AutoROIPanel);
            app.FilterbasedTab = uitab(app.TabGroup3);
            app.FilterbasedTab.Title = 'Filter based';
            app.Button_SelectDICpattern_AutoROI = uibutton(app.FilterbasedTab, 'push');
            app.Button_SelectDICpattern_AutoROI.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectDICpattern_AutoROIPushed, true);
            app.Button_SelectDICpattern_AutoROI.Text = 'Auto Filter';
            app.lowEditFieldLabel = uilabel(app.FilterbasedTab);
            app.lowEditFieldLabel.Text = 'low';
            app.lowEditField = uieditfield(app.FilterbasedTab, 'numeric');
            app.lowEditField.Limits = [0 1];
            app.highEditFieldLabel = uilabel(app.FilterbasedTab);
            app.highEditFieldLabel.Text = 'high';
            app.highEditField = uieditfield(app.FilterbasedTab, 'numeric');
            app.highEditField.Limits = [0 1];
            app.highEditField.Value = 1;
            app.filterboundsLabel = uilabel(app.FilterbasedTab);
            app.filterboundsLabel.Text = 'filter bounds';
            app.orderEditFieldLabel = uilabel(app.FilterbasedTab);
            app.orderEditFieldLabel.Text = 'order';
            app.orderEditField = uieditfield(app.FilterbasedTab, 'numeric');
            app.orderEditField.Limits = [1 10];
            app.orderEditField.Value = 3;
            app.graphsegmenterTab = uitab(app.TabGroup3);
            app.graphsegmenterTab.Title = 'graph segmenter';
            app.selectforegroundButton = uibutton(app.graphsegmenterTab, 'push');
            app.selectforegroundButton.ButtonPushedFcn = createCallbackFcn(app, @selectforegroundButtonPushed, true);
            app.selectforegroundButton.Text = 'select foreground';
            app.selectbackgroundButton = uibutton(app.graphsegmenterTab, 'push');
            app.selectbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @selectbackgroundButtonPushed, true);
            app.selectbackgroundButton.Text = 'select background';
            app.GenerateROIButton = uibutton(app.graphsegmenterTab, 'push');
            app.GenerateROIButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateROIButtonPushed, true);
            app.GenerateROIButton.Text = 'Generate ROI';
            app.ApplyROIPanel = uipanel(app.LoadROITab);
            app.ApplyROIPanel.Title = 'Apply ROI';
            app.Button_ClearROI = uibutton(app.ApplyROIPanel, 'push');
            app.Button_ClearROI.ButtonPushedFcn = createCallbackFcn(app, @Button_ClearROIPushed, true);
            app.Button_ClearROI.Text = 'Clear selected';
            app.Button_Applytoselected = uibutton(app.ApplyROIPanel, 'push');
            app.Button_Applytoselected.ButtonPushedFcn = createCallbackFcn(app, @Button_ApplytoselectedPushed, true);
            app.Button_Applytoselected.Text = 'Apply to selected';
            app.Button_Applytoall = uibutton(app.ApplyROIPanel, 'push');
            app.Button_Applytoall.ButtonPushedFcn = createCallbackFcn(app, @Button_ApplytoallPushed, true);
            app.Button_Applytoall.Text = 'Apply to all';
            app.Button_ClearAll = uibutton(app.ApplyROIPanel, 'push');
            app.Button_ClearAll.ButtonPushedFcn = createCallbackFcn(app, @Button_ClearAllPushed, true);
            app.Button_ClearAll.Text = 'Clear all';
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.AnalysisTabGroup_ALDIC = uitabgroup(app.StartTab);
            app.AnalysislistTab_ALDIC = uitab(app.AnalysisTabGroup_ALDIC);
            app.AnalysislistTab_ALDIC.Title = 'Start Analysis';
            app.AnalysisnameEditFieldLabel = uilabel(app.AnalysislistTab_ALDIC);
            app.AnalysisnameEditFieldLabel.Text = 'Analysis name';
            app.AnalysisnameEditField_ALDIC = uieditfield(app.AnalysislistTab_ALDIC, 'text');
            app.AnalysisnameEditField_ALDIC.ValueChangedFcn = createCallbackFcn(app, @AnalysisnameEditField_ALDICValueChanged, true);
            app.AnalysisnameEditField_ALDIC.ValueChangingFcn = createCallbackFcn(app, @AnalysisnameEditField_ALDICValueChanging, true);
            app.StartnewanalysisButton_ALDIC = uibutton(app.AnalysislistTab_ALDIC, 'push');
            app.StartnewanalysisButton_ALDIC.ButtonPushedFcn = createCallbackFcn(app, @StartnewanalysisButton_ALDICPushed, true);
            app.StartnewanalysisButton_ALDIC.Enable = 'off';
            app.StartnewanalysisButton_ALDIC.Text = 'Start new analysis';
            app.AnalysistypeALDICLabel = uilabel(app.AnalysislistTab_ALDIC);
            app.AnalysistypeALDICLabel.Text = 'Analysis type: ALDIC';
            app.SetinitialdisplacementguessTab_ALDIC = uitab(app.AnalysisTabGroup_ALDIC);
            app.SetinitialdisplacementguessTab_ALDIC.Title = 'Initial Guess';
            app.RadioGroup_InitialguessMethod_ALDIC = uibuttongroup(app.SetinitialdisplacementguessTab_ALDIC);
            app.RadioGroup_InitialguessMethod_ALDIC.SelectionChangedFcn = createCallbackFcn(app, @RadioGroup_InitialguessMethod_ALDICSelectionChanged, true);
            app.RadioGroup_InitialguessMethod_ALDIC.Title = 'Method';
            app.Radio_Multigridsearch_ALDIC = uiradiobutton(app.RadioGroup_InitialguessMethod_ALDIC);
            app.Radio_Multigridsearch_ALDIC.Text = 'Multigrid search';
            app.Radio_Multigridsearch_ALDIC.Value = true;
            app.Radio_Wholefieldsearch_ALDIC = uiradiobutton(app.RadioGroup_InitialguessMethod_ALDIC);
            app.Radio_Wholefieldsearch_ALDIC.Text = 'Whole field search';
            app.Radio_Manualseed_ALDIC = uiradiobutton(app.RadioGroup_InitialguessMethod_ALDIC);
            app.Radio_Manualseed_ALDIC.Enable = 'off';
            app.Radio_Manualseed_ALDIC.Text = 'Manual seed';
            app.StartsearchButton_ALDIC = uibutton(app.SetinitialdisplacementguessTab_ALDIC, 'push');
            app.StartsearchButton_ALDIC.ButtonPushedFcn = createCallbackFcn(app, @StartsearchButton_ALDICPushed, true);
            app.StartsearchButton_ALDIC.Text = 'Start search';
            app.SearchsettingsPanel_ALDIC = uipanel(app.SetinitialdisplacementguessTab_ALDIC);
            app.SearchsettingsPanel_ALDIC.Title = 'Search settings';
            app.searchregionsizeEditFieldLabel = uilabel(app.SearchsettingsPanel_ALDIC);
            app.searchregionsizeEditFieldLabel.Text = 'search region size';
            app.searchregionsizeEditField_ALDIC = uieditfield(app.SearchsettingsPanel_ALDIC, 'numeric');
            app.searchregionsizeEditField_ALDIC.Limits = [1 Inf];
            app.searchregionsizeEditField_ALDIC.RoundFractionalValues = 'on';
            app.searchregionsizeEditField_ALDIC.ValueChangedFcn = createCallbackFcn(app, @searchregionsizeEditField_ALDICValueChanged, true);
            app.searchregionsizeEditField_ALDIC.Tooltip = {'Should be larger than the maximum displacement'};
            app.searchregionsizeEditField_ALDIC.Value = 50;
            app.searchstepsizeEditFieldLabel = uilabel(app.SearchsettingsPanel_ALDIC);
            app.searchstepsizeEditFieldLabel.Text = 'search step size';
            app.searchstepsizeEditField_ALDIC = uieditfield(app.SearchsettingsPanel_ALDIC, 'numeric');
            app.searchstepsizeEditField_ALDIC.Limits = [1 Inf];
            app.searchstepsizeEditField_ALDIC.RoundFractionalValues = 'on';
            app.searchstepsizeEditField_ALDIC.ValueChangedFcn = createCallbackFcn(app, @searchstepsizeEditField_ALDICValueChanged, true);
            app.searchstepsizeEditField_ALDIC.Tooltip = {'sets the number of grid points '};
            app.searchstepsizeEditField_ALDIC.Value = 20;
            app.maxdisplacementEditFieldLabel = uilabel(app.SetinitialdisplacementguessTab_ALDIC);
            app.maxdisplacementEditFieldLabel.Text = 'max displacement';
            app.maxdisplacementEditField_ALDIC = uieditfield(app.SetinitialdisplacementguessTab_ALDIC, 'numeric');
            app.maxdisplacementEditField_ALDIC.Editable = 'off';
            app.maxdisplacementEditField_ALDIC.Tooltip = {'Will update upon each iteration'};
            app.Panel_2 = uipanel(app.SetinitialdisplacementguessTab_ALDIC);
            app.IDG_ReferenceImage_ALDIC = uiaxes(app.Panel_2);
            title(app.IDG_ReferenceImage_ALDIC, 'Reference ')
            app.IDG_ReferenceImage_ALDIC.XTick = [];
            app.IDG_ReferenceImage_ALDIC.YTick = [];
            app.IDG_DeformedImage_ALDIC = uiaxes(app.Panel_2);
            title(app.IDG_DeformedImage_ALDIC, 'Deformed')
            app.IDG_DeformedImage_ALDIC.XTick = [];
            app.IDG_DeformedImage_ALDIC.YTick = [];
            app.SetDICparametersTab_ALDIC = uitab(app.AnalysisTabGroup_ALDIC);
            app.SetDICparametersTab_ALDIC.Title = 'ALDIC';
            app.ALDICOptionsPanel_ALDIC = uipanel(app.SetDICparametersTab_ALDIC);
            app.ALDICOptionsPanel_ALDIC.Title = 'ALDIC Options';
            app.numberofparallelpoolsSpinnerLabel = uilabel(app.ALDICOptionsPanel_ALDIC);
            app.numberofparallelpoolsSpinnerLabel.Text = {'number of'; ' parallel pools'};
            app.numberofparallelpoolsSpinner_ALDIC = uispinner(app.ALDICOptionsPanel_ALDIC);
            app.numberofparallelpoolsSpinner_ALDIC.Limits = [1 Inf];
            app.numberofparallelpoolsSpinner_ALDIC.Value = 1;
            app.Subproblem2MethodButtonGroup_ALDIC = uibuttongroup(app.ALDICOptionsPanel_ALDIC);
            app.Subproblem2MethodButtonGroup_ALDIC.Title = 'Subproblem 2 Method';
            app.FiniteDifferenceButton_ALDIC = uiradiobutton(app.Subproblem2MethodButtonGroup_ALDIC);
            app.FiniteDifferenceButton_ALDIC.Text = 'Finite Difference';
            app.FiniteDifferenceButton_ALDIC.Value = true;
            app.FiniteElementMethodButton_ALDIC = uiradiobutton(app.Subproblem2MethodButtonGroup_ALDIC);
            app.FiniteElementMethodButton_ALDIC.Text = 'Finite Element Method';
            app.Subproblem1MethodButtonGroup_ALDIC = uibuttongroup(app.ALDICOptionsPanel_ALDIC);
            app.Subproblem1MethodButtonGroup_ALDIC.Title = 'Subproblem 1 Method';
            app.LevenbergMarquardtButton_ALDIC = uiradiobutton(app.Subproblem1MethodButtonGroup_ALDIC);
            app.LevenbergMarquardtButton_ALDIC.Text = 'Levenberg Marquardt';
            app.LevenbergMarquardtButton_ALDIC.Value = true;
            app.GaussNewtonButton_ALDIC = uiradiobutton(app.Subproblem1MethodButtonGroup_ALDIC);
            app.GaussNewtonButton_ALDIC.Enable = 'off';
            app.GaussNewtonButton_ALDIC.Text = 'Gauss Newton';
            app.windowsizeEditFieldLabel_2 = uilabel(app.ALDICOptionsPanel_ALDIC);
            app.windowsizeEditFieldLabel_2.Text = 'window size';
            app.windowsizeEditField_ALDIC = uieditfield(app.ALDICOptionsPanel_ALDIC, 'numeric');
            app.windowsizeEditField_ALDIC.Limits = [1 Inf];
            app.windowsizeEditField_ALDIC.Value = 50;
            app.stepsizeEditFieldLabel_2 = uilabel(app.ALDICOptionsPanel_ALDIC);
            app.stepsizeEditFieldLabel_2.Text = 'step size';
            app.stepsizeEditField_ALDIC = uieditfield(app.ALDICOptionsPanel_ALDIC, 'numeric');
            app.stepsizeEditField_ALDIC.Limits = [1 Inf];
            app.stepsizeEditField_ALDIC.Value = 20;
            app.toleranceEditFieldLabel_2 = uilabel(app.ALDICOptionsPanel_ALDIC);
            app.toleranceEditFieldLabel_2.Text = 'tolerance';
            app.toleranceEditField_ALDIC = uieditfield(app.ALDICOptionsPanel_ALDIC, 'numeric');
            app.toleranceEditField_ALDIC.Limits = [1e-15 Inf];
            app.toleranceEditField_ALDIC.Value = 0.01;
            app.muEditFieldLabel_2 = uilabel(app.ALDICOptionsPanel_ALDIC);
            app.muEditFieldLabel_2.Text = 'mu';
            app.muEditField_ALDIC = uieditfield(app.ALDICOptionsPanel_ALDIC, 'numeric');
            app.muEditField_ALDIC.Limits = [1e-15 Inf];
            app.muEditField_ALDIC.Value = 0.001;
            app.InitialguessoptionsPanel_ALDIC = uipanel(app.SetDICparametersTab_ALDIC);
            app.InitialguessoptionsPanel_ALDIC.Title = 'Initial guess options';
            app.UsePODCheckBox_ALDIC = uicheckbox(app.InitialguessoptionsPanel_ALDIC);
            app.UsePODCheckBox_ALDIC.Enable = 'off';
            app.UsePODCheckBox_ALDIC.Text = 'Use POD';
            app.UsePODCheckBox_ALDIC.Value = true;
            app.UsemostrecentframeasguessCheckBox_ALDIC = uicheckbox(app.InitialguessoptionsPanel_ALDIC);
            app.UsemostrecentframeasguessCheckBox_ALDIC.Text = 'Use most recent frame as guess';
            app.UsemostrecentframeasguessCheckBox_ALDIC.Value = true;
            app.StartAnalysis_ALDIC = uibutton(app.SetDICparametersTab_ALDIC, 'push');
            app.StartAnalysis_ALDIC.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysis_ALDICPushed, true);
            app.StartAnalysis_ALDIC.Text = 'Start Analysis';
            app.Panel_4 = uipanel(app.SetDICparametersTab_ALDIC);
            app.A_Udisp_ALDIC = uiaxes(app.Panel_4);
            title(app.A_Udisp_ALDIC, 'Reference ')
            app.A_Udisp_ALDIC.XTick = [];
            app.A_Udisp_ALDIC.YTick = [];
            app.A_Vdisp_ALDIC = uiaxes(app.Panel_4);
            title(app.A_Vdisp_ALDIC, 'Deformed')
            app.A_Vdisp_ALDIC.XTick = [];
            app.A_Vdisp_ALDIC.YTick = [];
            app.DisplacementsTab = uitab(app.TabGroup);
            app.DisplacementsTab.Title = 'Displacements';
            app.SelectAnalysisListBoxLabel = uilabel(app.DisplacementsTab);
            app.SelectAnalysisListBoxLabel.Text = 'Select Analysis';
            app.SelectAnalysisListBox_Displacements = uilistbox(app.DisplacementsTab);
            app.SelectAnalysisListBox_Displacements.Items = {};
            app.SelectAnalysisListBox_Displacements.Value = {};
            app.StrainsTab = uitab(app.TabGroup);
            app.StrainsTab.Title = 'Strains';
            app.SelectAnalysisListBoxLabel_4 = uilabel(app.StrainsTab);
            app.SelectAnalysisListBoxLabel_4.Text = 'Select Analysis';
            app.SelectAnalysisListBox_Strains = uilistbox(app.StrainsTab);
            app.SelectAnalysisListBox_Strains.Items = {};
            app.SelectAnalysisListBox_Strains.Value = {};
            app.ExportDataTab = uitab(app.TabGroup);
            app.ExportDataTab.Title = 'Export Data';
            app.SelectAnalysisListBoxLabel_5 = uilabel(app.ExportDataTab);
            app.SelectAnalysisListBoxLabel_5.Text = 'Select Analysis';
            app.SelectAnalysisListBox_Export = uilistbox(app.ExportDataTab);
            app.SelectAnalysisListBox_Export.Items = {};
            app.SelectAnalysisListBox_Export.Value = {};
            app.TypeLabel = uilabel(app.UIFigure);
            app.TypeLabel.Text = 'corALate ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CorALate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end